classdef sepa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EyesWindow            matlab.ui.container.Panel
        GoBackButton          matlab.ui.control.Button
        ExpandedPlot          matlab.ui.control.UIAxes
        Subplots              matlab.ui.container.Panel
        RestButton            matlab.ui.control.Button
        TestButton            matlab.ui.control.Button
        IntroButton           matlab.ui.control.Button
        AnalisisButton        matlab.ui.control.Button
        PruebasDropDown       matlab.ui.control.DropDown
        PruebasDropDownLabel  matlab.ui.control.Label
        rest                  matlab.ui.control.UIAxes
        test                  matlab.ui.control.UIAxes
        intro                 matlab.ui.control.UIAxes
        Mainmenu              matlab.ui.container.Panel
        DataButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        CargardatosButton     matlab.ui.control.Button
        Mainplot              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        blocks
        Introplot  matlab.graphics.chart.primitive.Line
        Testplot   matlab.graphics.chart.primitive.Line
        Restplot   matlab.graphics.chart.primitive.Line
        index      double
    end
    
    methods (Access = private)
        
        function ExpandedView(app,id)
            pos = app.Subplots.Position;
            app.Subplots.Visible = 'off';
            app.EyesWindow.Position = pos;  
            app.EyesWindow.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);
            
            if (id == 0)
                app.ExpandedPlot.Title.String = "Introduction (0-2s)";
                plot(app.ExpandedPlot,app.Introplot.XData,app.Introplot.YData)
            elseif (id == 1)
                app.ExpandedPlot.Title.String = "Motor Imagery Test (2-6s)";
                plot(app.ExpandedPlot,app.Testplot.XData,app.Testplot.YData)
            elseif (id == 2)
                app.ExpandedPlot.Title.String = "Rest (6-8s)";
                plot(app.ExpandedPlot,app.Restplot.XData,app.Restplot.YData)
            end    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Panel1 visible, Panel2 oculto
            app.Mainmenu.Visible = 'on';
            app.Subplots.Visible = 'off';
            app.EyesWindow.Visible = "off";

            % Ajustar posición del panel a [0,0] dentro de UIFigure
            app.Mainmenu.Position(1:2) = [0 0];
        
            % Ajustar tamaño de la UIFigure al panel
            app.UIFigure.Position(3:4) = app.Mainmenu.Position(3:4);
        end

        % Button pushed function: CargardatosButton
        function LoadData(app, event)
             % Diálogo para seleccionar archivo .mat
            [file, path] = uigetfile('*.edf', 'Seleccione un archivo edf');
        
            % Si el usuario cancela
            if isequal(file, 0)
                disp('El usuario canceló la operación.');
            else
                % Crea la ruta completa del archivo
                fullFileName = fullfile(path, file);
        
                % Carga el archivo
                data = edfread(fullFileName);
        
                % Extrae nombre y extensión del archivo
                [~, fileName, ext] = fileparts(fullFileName);
        
                % Muestra el nombre en un campo de texto del app
                app.Label.Text = [fileName, ext];
                sigL = vertcat(data.HEOL{:});
                sigR = vertcat(data.HEOR{:});
                EOG = sigL-sigR;
                fs = 500;
                t = (0:length(EOG)-1)/fs;

                plot(app.Mainplot,t,sigL)
                
                segLengths = [2, 4, 2] * fs;  % [2s, 4s, 2s] in samples
                totalBlockLength = sum(segLengths);  % 8*fs samples
                nBlocks = floor(length(sigL)/totalBlockLength);
                block(nBlocks) = struct('sig', [], 't', []);
                
                for k = 1:nBlocks
                    startIdx = (k-1)*totalBlockLength + 1;
                    
                    % Extract the full block
                    blockSig = sigL(startIdx : startIdx + totalBlockLength - 1);
                    blockT   = t(startIdx : startIdx + totalBlockLength - 1);
                    
                    % Split the block into the 3 subarrays
                    idx1 = 1 : segLengths(1);
                    idx2 = segLengths(1)+1 : sum(segLengths(1:2));
                    idx3 = sum(segLengths(1:2))+1 : totalBlockLength;
                    
                    block(k).sig = { blockSig(idx1), blockSig(idx2), blockSig(idx3) };
                    block(k).t   = { blockT(idx1), blockT(idx2), blockT(idx3) };
                end
                app.blocks = block;
            end
        end

        % Button pushed function: DataButton
        function ChangeWindow(app, event)
            pos = app.Mainmenu.Position;
            app.Mainmenu.Visible = 'off';
            app.Subplots.Position = pos;  
            app.Subplots.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);

            nBlocks = length(app.blocks);
            dropdownItems = arrayfun(@(k) ['Prueba' num2str(k)], 1:nBlocks, 'UniformOutput', false);
            app.PruebasDropDown.Items = dropdownItems;
            app.PruebasDropDown.Value = dropdownItems{1}; % poner la primera como seleccionada
            ChoosePrueba(app, event)
        end

        % Value changed function: PruebasDropDown
        function ChoosePrueba(app, event)
            selected = app.PruebasDropDown.Value;
                
            % Obtener número de prueba seleccionada
            n = str2double(extractAfter(selected, 'Prueba'));
            app.index = n;
            app.Introplot = plot(app.intro, app.blocks(n).t{1}, app.blocks(n).sig{1});
            app.Testplot = plot(app.test, app.blocks(n).t{2}, app.blocks(n).sig{2});
            app.Restplot = plot(app.rest, app.blocks(n).t{3}, app.blocks(n).sig{3});
        end

        % Button pushed function: IntroButton
        function ExpandedIntro(app, event)
            ExpandedView(app,0)
        end

        % Button pushed function: TestButton
        function ExpandedTest(app, event)
            ExpandedView(app,1)
        end

        % Button pushed function: RestButton
        function ExpandedRest(app, event)
            ExpandedView(app,2)
        end

        % Button pushed function: GoBackButton
        function Goback(app, event)
            pos = app.EyesWindow.Position;
            app.EyesWindow.Visible = 'off';
            app.Subplots.Position = pos;  
            app.Subplots.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mainmenu = uipanel(app.UIFigure);
            app.Mainplot = uiaxes(app.Mainmenu);
            title(app.Mainplot, 'Signal View')
            xlabel(app.Mainplot, 'X')
            ylabel(app.Mainplot, 'Y')
            zlabel(app.Mainplot, 'Z')
            app.CargardatosButton = uibutton(app.Mainmenu, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.Label = uilabel(app.Mainmenu);
            app.Label.Text = '';
            app.DataButton = uibutton(app.Mainmenu, 'push');
            app.DataButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeWindow, true);
            app.DataButton.Text = 'Data';
            app.Subplots = uipanel(app.UIFigure);
            app.intro = uiaxes(app.Subplots);
            title(app.intro, 'Introduction (0-2s)')
            xlabel(app.intro, 'X')
            ylabel(app.intro, 'Y')
            zlabel(app.intro, 'Z')
            app.test = uiaxes(app.Subplots);
            title(app.test, 'Motor Imagery Test (2-6s)')
            xlabel(app.test, 'X')
            ylabel(app.test, 'Y')
            zlabel(app.test, 'Z')
            app.rest = uiaxes(app.Subplots);
            title(app.rest, 'Rest (6-8s)')
            xlabel(app.rest, 'X')
            ylabel(app.rest, 'Y')
            zlabel(app.rest, 'Z')
            app.PruebasDropDownLabel = uilabel(app.Subplots);
            app.PruebasDropDownLabel.Text = 'Pruebas';
            app.PruebasDropDown = uidropdown(app.Subplots);
            app.PruebasDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePrueba, true);
            app.AnalisisButton = uibutton(app.Subplots, 'push');
            app.AnalisisButton.Text = 'Analisis';
            app.IntroButton = uibutton(app.Subplots, 'push');
            app.IntroButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedIntro, true);
            app.IntroButton.Text = 'Intro+';
            app.TestButton = uibutton(app.Subplots, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedTest, true);
            app.TestButton.Text = 'Test+';
            app.RestButton = uibutton(app.Subplots, 'push');
            app.RestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedRest, true);
            app.RestButton.Text = 'Rest+';
            app.EyesWindow = uipanel(app.UIFigure);
            app.ExpandedPlot = uiaxes(app.EyesWindow);
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            app.GoBackButton = uibutton(app.EyesWindow, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @Goback, true);
            app.GoBackButton.Text = 'Go Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sepa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end